classdef RampSpeedApp < matlab.apps.AppBase
    %RAMPSPEEDAPP App Designer shell for checkpointed ramp-speed studies.

    properties (Access = public)
        UIFigure matlab.ui.Figure
        RootGridLayout matlab.ui.container.GridLayout
        SetupPanel matlab.ui.container.Panel
        AnalysisPanel matlab.ui.container.Panel
        SetupGridLayout matlab.ui.container.GridLayout
        SetupTable matlab.ui.control.Table
        CarRoleDropDown matlab.ui.control.DropDown
        RampTypeDropDown matlab.ui.control.DropDown
        LateralModeDropDown matlab.ui.control.DropDown
        SpeedStartEditField matlab.ui.control.NumericEditField
        SpeedStopEditField matlab.ui.control.NumericEditField
        SpeedStepEditField matlab.ui.control.NumericEditField
        SpeedVectorEditField matlab.ui.control.EditField
        NRampEditField matlab.ui.control.NumericEditField
        NBisectEditField matlab.ui.control.NumericEditField
        ResidualToleranceEditField matlab.ui.control.NumericEditField
        ExecutionModeDropDown matlab.ui.control.DropDown
        WorkersEditField matlab.ui.control.NumericEditField
        UnitDropDown matlab.ui.control.DropDown
        BaselineDropDown matlab.ui.control.DropDown
        RunButton matlab.ui.control.Button
        CancelButton matlab.ui.control.Button
        LoadButton matlab.ui.control.Button
        SaveButton matlab.ui.control.Button
        ExportButton matlab.ui.control.Button
        ClearButton matlab.ui.control.Button
        ProgressTextArea matlab.ui.control.TextArea
        MainTabGroup matlab.ui.container.TabGroup
        CapabilityTab matlab.ui.container.Tab
        BalanceTab matlab.ui.container.Tab
        AeroLoadsTab matlab.ui.container.Tab
        SuspensionTab matlab.ui.container.Tab
        RawRampTab matlab.ui.container.Tab
        InspectorDataTab matlab.ui.container.Tab
        CapabilityAxes matlab.ui.control.UIAxes
        BalanceAxes matlab.ui.control.UIAxes
        AeroLoadsAxes matlab.ui.control.UIAxes
        SuspensionAxes matlab.ui.control.UIAxes
        RawRampAxes matlab.ui.control.UIAxes
        InspectorTable matlab.ui.control.Table
        Cars = {}
        Cases = struct.empty(0,1)
        DesignTable = table()
        RunCaseFcnForTest = []
    end

    properties (Access = private)
        Future = []
        CompletionFuture = []
        ProgressQueue = []
        CurrentStudy = []
        SelectedCaseIds = strings(0,1)
        BaselineCaseId = ""
        DisplayUnits = struct("speed","m/s","acceleration","m/s^2", ...
            "force","N","length","m","angle","rad", ...
            "angularRate","rad/s")
        CheckpointPath = ""
        AppVersion = "ramp-speed-app-1"
        LastEvents = struct.empty(0,1)
        LastPlotData = []
        LastComparison = []
        LastInspectorTable = table()
        CancelRequested = false
        CapabilityHandles = struct()
        BalanceHandles = struct()
        AeroLoadsHandles = struct()
        SuspensionHandles = struct()
        RawRampHandles = struct()
        ComparisonHandles = struct()
    end

    methods (Access = public)
        function app = RampSpeedApp(varargin)
            createComponents(app);
            registerApp(app,app.UIFigure);

            p = inputParser;
            addParameter(p,"Visible","on");
            addParameter(p,"Cars",[]);
            addParameter(p,"DesignTable",table());
            addParameter(p,"CheckpointPath","");
            addParameter(p,"AppVersion","ramp-speed-app-1");
            parse(p,varargin{:});

            app.AppVersion = string(p.Results.AppVersion);
            app.CheckpointPath = string(p.Results.CheckpointPath);
            if strlength(app.CheckpointPath) == 0
                app.CheckpointPath = fullfile(tempdir, ...
                    "RampSpeedApp-checkpoint.mat");
            end
            if isempty(p.Results.Cars)
                app.Cars = defaultCars();
            else
                app.Cars = p.Results.Cars;
            end
            if isempty(p.Results.DesignTable)
                app.DesignTable = defaultDesignTable();
            else
                app.DesignTable = p.Results.DesignTable;
            end

            app.UIFigure.Visible = char(string(p.Results.Visible));
            app.refreshCaseCatalog();
            app.updateUnitSelection();
            app.ProgressTextArea.Value = "Ready.";
            if nargout == 0
                clear app
            end
        end

        function request = buildRequestForTest(app)
            %BUILDREQUESTFORTEST Expose the production request builder to UI tests.
            request = app.buildRequest();
        end

        function [study,events] = runStudyForTest(app,cars,cases,runCaseFcn)
            %RUNSTUDYFORTEST Run a deterministic injected fixture without fmincon.
            app.Cars = cars;
            app.Cases = cases(:);
            app.SelectedCaseIds = string({app.Cases.id}).';
            app.RunCaseFcnForTest = runCaseFcn;
            app.ExecutionModeDropDown.Value = "serial";
            app.refreshSetupTable();

            request = app.buildRequest();
            request.parallelRequested = false;
            request.numWorkers = 0;
            request.checkpointPath = "";
            callbacks = struct("onProgress", ...
                @(event)app.onProgressEvent(event));
            [study,events] = rampSpeed.runStudy(app.Cars,app.selectedCases(), ...
                request,callbacks);
            app.handleStudyResult(study,events);
        end

        function setModelData(app,cars,designTable)
            %SETMODELDATA Replace the model catalog used by the shell.
            app.Cars = cars;
            app.DesignTable = designTable;
            app.refreshCaseCatalog();
        end

        function delete(app)
            try
                if ~isempty(app.Future) && isvalid(app.Future)
                    cancel(app.Future);
                end
            catch
            end
            try
                if ~isempty(app.CompletionFuture) && ...
                        isvalid(app.CompletionFuture)
                    cancel(app.CompletionFuture);
                end
            catch
            end
            try
                if ~isempty(app.ProgressQueue)
                    delete(app.ProgressQueue);
                end
            catch
            end
            if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                delete(app.UIFigure);
            end
        end

        function RunButtonPushed(app,~,~)
            try
                request = app.buildRequest();
                app.CancelRequested = false;
                app.setBusyState(true);
                app.ProgressTextArea.Value = "Starting study...";

                app.ProgressQueue = parallel.pool.DataQueue;
                afterEach(app.ProgressQueue, ...
                    @(event)app.onProgressEvent(event));
                request.progressQueue = app.ProgressQueue;
                cases = app.selectedCases();
                callbacks = struct();
                app.Future = parfeval(backgroundPool,@rampSpeed.runStudy,2, ...
                    app.Cars,cases,request,callbacks);
                app.CompletionFuture = afterAll(app.Future, ...
                    @(study,events)app.onFutureComplete(study,events),0);
            catch ME
                app.setBusyState(false);
                app.showError(ME);
            end
        end

        function CancelButtonPushed(app,~,~)
            app.CancelRequested = true;
            if isempty(app.Future)
                app.appendProgress("No study is running.");
                app.setBusyState(false);
                return
            end
            try
                cancel(app.Future);
                wait(app.Future);
            catch
            end
            app.reloadCheckpointAfterCancel();
            app.setBusyState(false);
            app.appendProgress("Study cancelled; latest checkpoint retained.");
        end

        function LoadButtonPushed(app,~,~)
            [fileName,pathName] = uigetfile("*.mat","Load ramp-speed study");
            if isequal(fileName,0)
                return
            end
            try
                app.loadStudyFromFile(fullfile(pathName,fileName));
            catch ME
                app.showError(ME);
            end
        end

        function SaveButtonPushed(app,~,~)
            if isempty(app.CurrentStudy)
                app.appendProgress("Nothing to save.");
                return
            end
            [fileName,pathName] = uiputfile("*.mat","Save ramp-speed study");
            if isequal(fileName,0)
                return
            end
            try
                rampSpeed.saveStudy(fullfile(pathName,fileName),app.CurrentStudy);
                app.appendProgress("Study saved: " + string(fileName));
            catch ME
                app.showError(ME);
            end
        end

        function ExportButtonPushed(app,~,~)
            if isempty(app.CurrentStudy) || isempty(app.LastInspectorTable)
                app.appendProgress("Nothing to export.");
                return
            end
            [fileName,pathName] = uiputfile("*.csv","Export inspector data");
            if isequal(fileName,0)
                return
            end
            try
                writetable(app.LastInspectorTable,fullfile(pathName,fileName));
                app.appendProgress("Inspector data exported: " + string(fileName));
            catch ME
                app.showError(ME);
            end
        end

        function ClearButtonPushed(app,~,~)
            app.CurrentStudy = [];
            app.LastEvents = struct.empty(0,1);
            app.LastPlotData = [];
            app.LastComparison = [];
            app.LastInspectorTable = table();
            app.ProgressTextArea.Value = "Ready.";
            app.clearAxes();
            app.InspectorTable.Data = table();
            app.setBusyState(false);
        end
    end

    methods (Access = private)
        function createComponents(app)
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            app.UIFigure = uifigure("Visible","off");
            app.UIFigure.Name = "Ramp-Speed Study";
            app.UIFigure.CloseRequestFcn = @(~,~)delete(app);
            app.RootGridLayout = uigridlayout(app.UIFigure,[1 2]);
            app.SetupPanel = uipanel(app.RootGridLayout);
            app.SetupPanel.Title = "Run / Setup";
            app.SetupGridLayout = uigridlayout(app.SetupPanel,[20 2]);
            app.SetupGridLayout.RowHeight{2} = "120x";
            app.SetupGridLayout.RowHeight{18} = "90x";
            heading = uilabel(app.SetupGridLayout,"Text", ...
                "Available setups");
            heading.FontWeight = "bold";
            heading.Layout.Row = 1;
            heading.Layout.Column = [1 2];
            app.SetupTable = uitable(app.SetupGridLayout);
            app.SetupTable.ColumnName = {"ID","Label","Role","Source"};
            app.SetupTable.Multiselect = "on";
            app.SetupTable.CellSelectionCallback = ...
                @(src,event)app.setupSelectionChanged(event);
            makeLabel(app.SetupGridLayout,"Car role",3,1);
            app.CarRoleDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Lap car','Acceleration car'}, ...
                "ItemsData",{'lap','acceleration'},"Value","lap");
            app.CarRoleDropDown.ValueChangedFcn = ...
                @(src,event)app.caseSelectionChanged(src,event);
            makeLabel(app.SetupGridLayout,"Ramp type",4,1);
            app.RampTypeDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Lateral limit','Pure longitudinal'}, ...
                "ItemsData",{'lateral','longitudinal'},"Value","lateral");
            app.RampTypeDropDown.ValueChangedFcn = ...
                @(src,event)app.rampTypeChanged(src,event);
            makeLabel(app.SetupGridLayout,"Lateral mode",5,1);
            app.LateralModeDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Coast','Balanced'}, ...
                "ItemsData",{'coast','balanced'},"Value","coast");
            makeLabel(app.SetupGridLayout,"Speed start",6,1);
            app.SpeedStartEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",5,"Limits",[0 Inf]);
            makeLabel(app.SetupGridLayout,"Speed stop",7,1);
            app.SpeedStopEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",25,"Limits",[0 Inf]);
            makeLabel(app.SetupGridLayout,"Speed step",8,1);
            app.SpeedStepEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",5,"Limits",[0 Inf]);
            vectorLabel = uilabel(app.SetupGridLayout,"Text", ...
                "Explicit speeds");
            vectorLabel.Layout.Row = 9;
            vectorLabel.Layout.Column = 1;
            app.SpeedVectorEditField = uieditfield(app.SetupGridLayout, ...
                "text","Value","");
            app.SpeedVectorEditField.Placeholder = "e.g. 5, 7.5, 10";
            makeLabel(app.SetupGridLayout,"Ramp points",10,1);
            app.NRampEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",10,"Limits",[0 Inf], ...
                "RoundFractionalValues","on");
            makeLabel(app.SetupGridLayout,"Bisections",11,1);
            app.NBisectEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",8,"Limits",[-Inf Inf], ...
                "RoundFractionalValues","on");
            makeLabel(app.SetupGridLayout,"Residual tolerance",12,1);
            app.ResidualToleranceEditField = uieditfield( ...
                app.SetupGridLayout,"numeric","Value",1e-6,"Limits",[0 Inf]);
            makeLabel(app.SetupGridLayout,"Execution",13,1);
            app.ExecutionModeDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'Serial','Parallel'}, ...
                "ItemsData",{'serial','parallel'},"Value","serial");
            makeLabel(app.SetupGridLayout,"Workers",14,1);
            app.WorkersEditField = uieditfield(app.SetupGridLayout, ...
                "numeric","Value",2,"Limits",[0 Inf], ...
                "RoundFractionalValues","on");
            makeLabel(app.SetupGridLayout,"Units",15,1);
            app.UnitDropDown = uidropdown(app.SetupGridLayout, ...
                "Items",{'SI','Imperial'}, ...
                "ItemsData",{'SI','imperial'},"Value","SI");
            app.UnitDropDown.ValueChangedFcn = ...
                @(src,event)app.unitChanged(src,event);
            makeLabel(app.SetupGridLayout,"Baseline",16,1);
            app.BaselineDropDown = uidropdown(app.SetupGridLayout);
            app.BaselineDropDown.ValueChangedFcn = ...
                @(src,event)app.baselineChanged(src,event);
            buttonGrid = uigridlayout(app.SetupGridLayout,[2 3]);
            buttonGrid.Layout.Row = 17;
            buttonGrid.Layout.Column = [1 2];
            buttonGrid.ColumnWidth = {"1x","1x","1x"};
            buttonGrid.RowHeight = {"1x","1x"};
            app.RunButton = uibutton(buttonGrid,"Text","Run", ...
                "ButtonPushedFcn",@(src,event)app.RunButtonPushed(src,event));
            app.CancelButton = uibutton(buttonGrid,"Text","Cancel", ...
                "Enable","off", ...
                "ButtonPushedFcn",@(src,event)app.CancelButtonPushed(src,event));
            app.ClearButton = uibutton(buttonGrid,"Text","Clear", ...
                "ButtonPushedFcn",@(src,event)app.ClearButtonPushed(src,event));
            app.LoadButton = uibutton(buttonGrid,"Text","Load", ...
                "ButtonPushedFcn",@(src,event)app.LoadButtonPushed(src,event));
            app.SaveButton = uibutton(buttonGrid,"Text","Save", ...
                "ButtonPushedFcn",@(src,event)app.SaveButtonPushed(src,event));
            app.ExportButton = uibutton(buttonGrid,"Text","Export", ...
                "ButtonPushedFcn",@(src,event)app.ExportButtonPushed(src,event));
            progressLabel = uilabel(app.SetupGridLayout,"Text", ...
                "Progress");
            progressLabel.FontWeight = "bold";
            progressLabel.Layout.Row = 18;
            progressLabel.Layout.Column = [1 2];
            app.ProgressTextArea = uitextarea(app.SetupGridLayout, ...
                "Editable","off","Value","Ready.");
            hint = uilabel(app.SetupGridLayout,"Text", ...
                "Selected setups are retained across runs.");
            hint.FontAngle = "italic";
            hint.Layout.Row = 20;
            hint.Layout.Column = [1 2];
            app.AnalysisPanel = uipanel(app.RootGridLayout);
            app.MainTabGroup = uitabgroup(app.AnalysisPanel);
            app.CapabilityTab = uitab(app.MainTabGroup,"Title","Capability");
            app.CapabilityAxes = uiaxes(app.CapabilityTab);
            title(app.CapabilityAxes,"Capability");
            xlabel(app.CapabilityAxes,"speed (m/s)");
            app.BalanceTab = uitab(app.MainTabGroup,"Title","Balance");
            app.BalanceAxes = uiaxes(app.BalanceTab);
            title(app.BalanceAxes,"Balance");
            app.AeroLoadsTab = uitab(app.MainTabGroup,"Title","Aero & Loads");
            app.AeroLoadsAxes = uiaxes(app.AeroLoadsTab);
            title(app.AeroLoadsAxes,"Aero & Loads");
            app.SuspensionTab = uitab(app.MainTabGroup,"Title","Suspension");
            app.SuspensionAxes = uiaxes(app.SuspensionTab);
            title(app.SuspensionAxes,"Suspension");
            app.RawRampTab = uitab(app.MainTabGroup,"Title","Raw Ramp");
            app.RawRampAxes = uiaxes(app.RawRampTab);
            title(app.RawRampAxes,"Raw Ramp");
            app.InspectorDataTab = uitab(app.MainTabGroup, ...
                "Title","Inspector/Data");
            app.InspectorTable = uitable(app.InspectorDataTab);
        end

        function refreshCaseCatalog(app)
            role = string(app.CarRoleDropDown.Value);
            app.Cases = rampSpeed.setupCaseCatalog(app.Cars, ...
                app.DesignTable,role);
            app.SelectedCaseIds = string({app.Cases.id}).';
            if isempty(app.Cases)
                app.BaselineCaseId = "";
            else
                app.BaselineCaseId = string(app.Cases(1).id);
            end
            app.refreshSetupTable();
        end

        function refreshSetupTable(app)
            if isempty(app.Cases)
                app.SetupTable.Data = table(strings(0,1),strings(0,1), ...
                    strings(0,1),strings(0,1), ...
                    'VariableNames',{'id','label','role','source'});
                app.BaselineDropDown.Items = {};
                app.BaselineDropDown.ItemsData = {};
                app.BaselineDropDown.Value = {};
                return
            end
            ids = string({app.Cases.id}).';
            labels = string({app.Cases.label}).';
            roles = string({app.Cases.carRole}).';
            sources = string({app.Cases.source}).';
            app.SetupTable.Data = table(ids,labels,roles,sources, ...
                'VariableNames',{'id','label','role','source'});
            app.BaselineDropDown.Items = cellstr(ids);
            app.BaselineDropDown.ItemsData = cellstr(ids);
            if isempty(app.BaselineCaseId) || ~ismember(app.BaselineCaseId,ids)
                app.BaselineCaseId = ids(1);
            end
            app.BaselineDropDown.Value = char(app.BaselineCaseId);
        end

        function setupSelectionChanged(app,event)
            if isempty(event) || ~isfield(event,"Indices") || ...
                    isempty(event.Indices)
                app.SelectedCaseIds = string({app.Cases.id}).';
                return
            end
            rows = unique(event.Indices(:,1));
            rows = rows(rows >= 1 & rows <= numel(app.Cases));
            if isempty(rows)
                app.SelectedCaseIds = string({app.Cases.id}).';
            else
                ids = string({app.Cases(rows).id}).';
                app.SelectedCaseIds = ids;
                if ~ismember(app.BaselineCaseId,ids)
                    app.BaselineCaseId = ids(1);
                    app.BaselineDropDown.Value = char(ids(1));
                end
            end
        end

        function caseSelectionChanged(app,~,~)
            app.refreshCaseCatalog();
        end

        function rampTypeChanged(app,~,~)
            if string(app.RampTypeDropDown.Value) == "longitudinal"
                app.CarRoleDropDown.Value = "acceleration";
            else
                app.CarRoleDropDown.Value = "lap";
            end
            app.refreshCaseCatalog();
        end

        function unitChanged(app,~,~)
            app.updateUnitSelection();
            app.refreshAnalysis();
        end

        function updateUnitSelection(app)
            if string(app.UnitDropDown.Value) == "imperial"
                app.DisplayUnits = struct("speed","mph", ...
                    "acceleration","g","force","lbf","length","in", ...
                    "angle","deg","angularRate","rps");
            else
                app.DisplayUnits = struct("speed","m/s", ...
                    "acceleration","m/s^2","force","N","length","m", ...
                    "angle","rad","angularRate","rad/s");
            end
        end

        function baselineChanged(app,~,~)
            app.BaselineCaseId = string(app.BaselineDropDown.Value);
            app.refreshAnalysis();
        end

        function request = buildRequest(app)
            rampType = lower(strtrim(string(app.RampTypeDropDown.Value)));
            if ~ismember(rampType,["lateral","longitudinal"])
                invalidRequest(app,"Ramp type must be lateral or longitudinal.");
            end
            mode = lower(strtrim(string(app.LateralModeDropDown.Value)));
            if ~ismember(mode,["coast","balanced"])
                invalidRequest(app,"Lateral mode must be coast or balanced.");
            end
            role = lower(strtrim(string(app.CarRoleDropDown.Value)));
            if ~ismember(role,["lap","acceleration"])
                invalidRequest(app,"Car role must be lap or acceleration.");
            end

            explicit = parseSpeedVector(app.SpeedVectorEditField.Value);
            if isempty(explicit)
                startSpeed = scalarNumber(app.SpeedStartEditField.Value);
                stopSpeed = scalarNumber(app.SpeedStopEditField.Value);
                stepSpeed = scalarNumber(app.SpeedStepEditField.Value);
                if ~isfinite(startSpeed) || ~isfinite(stopSpeed) || ...
                        ~isfinite(stepSpeed) || startSpeed <= 0 || ...
                        stopSpeed <= 0 || stepSpeed <= 0 || stopSpeed < startSpeed
                    invalidRequest(app, ...
                        "Start, stop, and step speeds must be positive with stop >= start.");
                end
                speeds = startSpeed:stepSpeed:stopSpeed;
                if isempty(speeds)
                    invalidRequest(app,"The generated speed vector is empty.");
                end
                if speeds(end) < stopSpeed
                    speeds(end+1) = stopSpeed;
                end
            else
                speeds = explicit;
            end
            if any(~isfinite(speeds)) || any(speeds <= 0)
                invalidRequest(app,"All speeds must be finite and positive.");
            end
            nRamp = scalarNumber(app.NRampEditField.Value);
            nBisect = scalarNumber(app.NBisectEditField.Value);
            tolerance = scalarNumber(app.ResidualToleranceEditField.Value);
            if ~isfinite(nRamp) || nRamp < 2 || nRamp ~= round(nRamp)
                invalidRequest(app,"nRamp must be an integer greater than or equal to 2.");
            end
            if ~isfinite(nBisect) || nBisect < 0 || nBisect ~= round(nBisect)
                invalidRequest(app,"nBisect must be a nonnegative integer.");
            end
            if ~isfinite(tolerance) || tolerance <= 0
                invalidRequest(app,"Residual tolerance must be greater than zero.");
            end

            execution = lower(strtrim(string(app.ExecutionModeDropDown.Value)));
            parallelRequested = execution == "parallel";
            workers = scalarNumber(app.WorkersEditField.Value);
            if ~isfinite(workers) || workers < 0 || workers ~= round(workers)
                invalidRequest(app,"Workers must be a nonnegative integer.");
            end

            settings = struct("speeds",speeds,"mode",mode, ...
                "nRamp",nRamp,"nBisect",nBisect, ...
                "residualTolerance",tolerance,"verbose",false, ...
                "carRole",role);
            request = struct("rampType",rampType,"carRole",role, ...
                "settings",settings,"parallelRequested",parallelRequested, ...
                "numWorkers",workers,"checkpointPath",app.CheckpointPath, ...
                "appVersion",app.AppVersion,"displayUnits",app.DisplayUnits);
            if ~isempty(app.RunCaseFcnForTest)
                request.runCaseFcn = app.RunCaseFcnForTest;
            end
        end

        function invalidRequest(~,message)
            error("rampSpeed:app:invalidRequest",string(message));
        end

        function [study,events] = runStudyNow(app,request,cases)
            callbacks = struct();
            [study,events] = rampSpeed.runStudy(app.Cars,cases,request,callbacks);
        end

        function onFutureComplete(app,study,events)
            if ~isvalid(app)
                return
            end
            app.handleStudyResult(study,events);
            app.Future = [];
            app.CompletionFuture = [];
        end

        function handleStudyResult(app,study,events)
            app.CurrentStudy = study;
            app.LastEvents = events;
            app.setBusyState(false);
            app.refreshAnalysis();
            if isfield(study,"status")
                status = lower(string(study.status));
            else
                status = "unknown";
            end
            switch status
                case "complete"
                    message = "Study complete.";
                case "cancelled"
                    message = "Study cancelled.";
                otherwise
                    message = "Study " + status + ".";
            end
            app.appendProgress(message);
        end

        function onProgressEvent(app,event)
            if ~isvalid(app) || ~isstruct(event) || ~isscalar(event)
                return
            end
            if isfield(event,"message")
                message = string(event.message);
            else
                message = "Study running...";
            end
            if strlength(message) == 0
                message = "Study running...";
            end
            value = string(app.ProgressTextArea.Value);
            value(end+1,1) = message;
            value = value(max(1,end-99):end);
            app.ProgressTextArea.Value = cellstr(value);
        end

        function setBusyState(app,isBusy)
            if isempty(app.UIFigure) || ~isvalid(app.UIFigure)
                return
            end
            if isBusy
                app.RunButton.Enable = "off";
                app.CancelButton.Enable = "on";
                app.LoadButton.Enable = "off";
                app.SaveButton.Enable = "off";
                app.ClearButton.Enable = "off";
            else
                app.RunButton.Enable = "on";
                app.CancelButton.Enable = "off";
                app.LoadButton.Enable = "on";
                app.SaveButton.Enable = "on";
                app.ClearButton.Enable = "on";
            end
        end

        function cases = selectedCases(app)
            if isempty(app.Cases)
                cases = app.Cases;
                return
            end
            ids = string({app.Cases.id}).';
            wanted = string(app.SelectedCaseIds(:));
            if isempty(wanted)
                wanted = ids;
            end
            mask = ismember(ids,wanted);
            if ~any(mask)
                mask(:) = true;
            end
            cases = app.Cases(mask);
        end

        function loadStudyFromFile(app,fileName)
            study = rampSpeed.loadStudy(fileName,app.AppVersion);
            app.CurrentStudy = study;
            if isfield(study,"cases") && ~isempty(study.cases)
                app.Cases = study.cases(:);
                app.SelectedCaseIds = string({app.Cases.id}).';
                app.BaselineCaseId = app.SelectedCaseIds(1);
                app.refreshSetupTable();
            end
            app.refreshAnalysis();
            app.appendProgress("Study loaded: " + string(fileName));
        end

        function reloadCheckpointAfterCancel(app)
            if strlength(app.CheckpointPath) == 0 || ...
                    ~isfile(app.CheckpointPath)
                return
            end
            try
                study = rampSpeed.loadStudy(app.CheckpointPath,app.AppVersion);
                app.CurrentStudy = study;
                app.refreshAnalysis();
            catch ME
                app.appendProgress("Checkpoint reload failed: " + string(ME.message));
            end
        end

        function refreshAnalysis(app)
            if isempty(app.CurrentStudy) || ...
                    ~isfield(app.CurrentStudy,"runs") || ...
                    isempty(app.CurrentStudy.runs)
                app.clearAxes();
                app.InspectorTable.Data = table();
                return
            end
            runs = app.CurrentStudy.runs;
            labels = string({runs.caseId}).';
            isLongitudinal = false;
            if isfield(app.CurrentStudy,"runMeta") && ...
                    isfield(app.CurrentStudy.runMeta,"rampType")
                isLongitudinal = string(app.CurrentStudy.runMeta.rampType) == ...
                    "longitudinal";
            elseif isfield(runs,"type")
                isLongitudinal = string(runs(1).type) == "longitudinal";
            end

            if isLongitudinal
                capabilityMetric = "drag";
                aeroMetric = "downforce";
                rawMetric = "raw_aLong";
            else
                capabilityMetric = "capability_free";
                aeroMetric = "downforce";
                rawMetric = "raw_aLat";
            end

            capabilityData = rampSpeed.buildPlotData(runs,capabilityMetric, ...
                struct("labels",labels));
            app.LastPlotData = app.prepareDisplayPlotData(capabilityData);
            app.CapabilityHandles = app.renderData(app.CapabilityAxes, ...
                app.LastPlotData);

            aeroData = rampSpeed.buildPlotData(runs,aeroMetric, ...
                struct("labels",labels));
            aeroData = app.prepareDisplayPlotData(aeroData);
            app.AeroLoadsHandles = app.renderData(app.AeroLoadsAxes,aeroData);

            suspensionData = rampSpeed.buildPlotData(runs,"front_ride_height", ...
                struct("labels",labels));
            suspensionData = app.prepareDisplayPlotData(suspensionData);
            app.SuspensionHandles = app.renderData(app.SuspensionAxes, ...
                suspensionData);

            rawData = rampSpeed.buildPlotData(runs,rawMetric, ...
                struct("labels",labels));
            rawData = app.prepareDisplayPlotData(rawData);
            app.RawRampHandles = app.renderData(app.RawRampAxes,rawData);

            app.LastComparison = [];
            app.ComparisonHandles = struct();
            baseline = string(app.BaselineCaseId);
            if isLongitudinal
                app.renderMessage(app.BalanceAxes, ...
                    "Balance is lateral-only for this run type.");
                app.BalanceHandles = struct();
            else
                balanceMetric = "handling_balance";
                balanceData = rampSpeed.buildPlotData(runs,balanceMetric, ...
                    struct("labels",labels));
                balanceData = app.prepareDisplayPlotData(balanceData);
                app.BalanceHandles = app.renderData(app.BalanceAxes, ...
                    balanceData);
                if numel(runs) > 1 && strlength(baseline) > 0
                    try
                        app.LastComparison = rampSpeed.buildComparison(runs, ...
                            balanceMetric,baseline,struct());
                        comparisonData = app.comparisonPlotData( ...
                            app.LastComparison);
                        comparisonData = app.prepareDisplayPlotData( ...
                            comparisonData);
                        app.ComparisonHandles = app.renderData( ...
                            app.BalanceAxes,comparisonData);
                        title(app.BalanceAxes,"Delta: variant - " + baseline);
                        ylabel(app.BalanceAxes,"Delta " + ...
                            string(comparisonData.yLabel));
                    catch ME
                        app.LastComparison = [];
                        app.ComparisonHandles = struct();
                        app.renderMessage(app.BalanceAxes, ...
                            "Comparison unavailable: " + string(ME.message));
                    end
                end
            end

            selection = struct("speedIndex",1,"pointIndex",1);
            try
                app.LastInspectorTable = rampSpeed.buildInspectorTable( ...
                    runs(1),selection,app.DisplayUnits);
                app.InspectorTable.Data = app.LastInspectorTable;
            catch
                app.LastInspectorTable = table();
                app.InspectorTable.Data = table();
            end
        end

        function handles = renderData(~,ax,data)
            cla(ax);
            handles = rampSpeed.renderMetric(ax,data, ...
                struct("showLegend",true,"showWarnings",true));
        end

        function data = comparisonPlotData(~,comparison)
            data = struct("metricId",comparison.metricId, ...
                "metric",comparison.metric,"sourceLevel",comparison.sourceLevel, ...
                "xLabel",comparison.xLabel,"yLabel",comparison.yLabel, ...
                "series",comparison.series);
            for i = 1:numel(data.series)
                data.series(i).label = string(data.series(i).label) + ...
                    " - " + string(comparison.baselineId);
            end
        end

        function data = prepareDisplayPlotData(app,data)
            if isempty(data) || ~isfield(data,"metric")
                return
            end
            metric = data.metric;
            targetUnit = app.targetMetricUnit(metric);
            data.metric.displayUnits = targetUnit;
            data.xLabel = replace(string(data.xLabel),"m/s", ...
                string(app.DisplayUnits.speed));
            data.yLabel = app.replaceUnitLabel(data.yLabel, ...
                string(metric.displayUnits),targetUnit);
            speedFactor = 1;
            if string(app.DisplayUnits.speed) == "mph"
                speedFactor = 2.2369362920544;
            end
            for i = 1:numel(data.series)
                data.series(i).x = double(data.series(i).x) * speedFactor;
                data.series(i).values = app.convertMetricValues( ...
                    data.series(i).values,metric,targetUnit);
                if isfield(data.series(i),"groups") && ...
                        ~isempty(data.series(i).groups)
                    for j = 1:numel(data.series(i).groups)
                        data.series(i).groups(j).x = ...
                            double(data.series(i).groups(j).x) * speedFactor;
                        data.series(i).groups(j).values = ...
                            app.convertMetricValues(data.series(i).groups(j).values, ...
                            metric,targetUnit);
                    end
                end
            end
        end

        function unit = targetMetricUnit(app,metric)
            stored = string(metric.storedUnits);
            switch stored
                case "m/s^2"
                    unit = string(app.DisplayUnits.acceleration);
                case "N"
                    unit = string(app.DisplayUnits.force);
                case "m"
                    unit = string(app.DisplayUnits.length);
                case "rad"
                    unit = string(app.DisplayUnits.angle);
                case "rad/s"
                    unit = string(app.DisplayUnits.angularRate);
                case "rad/(m/s^2)"
                    if string(app.DisplayUnits.angle) == "rad" && ...
                            string(app.DisplayUnits.acceleration) == "m/s^2"
                        unit = "rad/(m/s^2)";
                    elseif string(app.DisplayUnits.angle) == "deg" && ...
                            string(app.DisplayUnits.acceleration) == "g"
                        unit = "deg/g";
                    else
                        unit = string(app.DisplayUnits.angle) + "/(" + ...
                            string(app.DisplayUnits.acceleration) + ")";
                    end
                otherwise
                    unit = string(metric.displayUnits);
            end
        end

        function values = convertMetricValues(~,values,metric,targetUnit)
            values = double(values);
            scale = double(metric.scale);
            if isempty(scale) || ~isfinite(scale) || scale == 0
                scale = 1;
            end
            raw = values ./ scale;
            stored = string(metric.storedUnits);
            targetUnit = string(targetUnit);
            switch stored
                case "m/s^2"
                    if targetUnit == "g"
                        values = raw ./ 9.80665;
                    else
                        values = raw;
                    end
                case "N"
                    if targetUnit == "lbf"
                        values = raw ./ 4.4482216152605;
                    else
                        values = raw;
                    end
                case "m"
                    if targetUnit == "in"
                        values = raw .* 39.37007874015748;
                    else
                        values = raw;
                    end
                case "rad"
                    if targetUnit == "deg"
                        values = raw .* 180/pi;
                    else
                        values = raw;
                    end
                case "rad/s"
                    if targetUnit == "rps"
                        values = raw ./ (2*pi);
                    else
                        values = raw;
                    end
                case "rad/(m/s^2)"
                    if targetUnit == "deg/g"
                        values = raw .* 9.80665 .* 180/pi;
                    elseif targetUnit == "deg/(m/s^2)"
                        values = raw .* 180/pi;
                    elseif targetUnit == "rad/g"
                        values = raw .* 9.80665;
                    else
                        values = raw;
                    end
                case "fraction"
                    if targetUnit == "%"
                        values = raw .* 100;
                    else
                        values = raw;
                    end
                otherwise
                    values = raw .* scale;
            end
        end

        function label = replaceUnitLabel(~,label,sourceUnit,targetUnit)
            label = string(label);
            sourceUnit = string(sourceUnit);
            targetUnit = string(targetUnit);
            if strlength(sourceUnit) > 0 && sourceUnit ~= targetUnit
                label = replace(label,"(" + sourceUnit + ")", ...
                    "(" + targetUnit + ")");
            end
        end
        function renderMessage(~,ax,message)
            cla(ax);
            title(ax,message);
            grid(ax,"on");
        end

        function clearAxes(app)
            axesList = [app.CapabilityAxes,app.BalanceAxes, ...
                app.AeroLoadsAxes,app.SuspensionAxes,app.RawRampAxes];
            for ax = axesList(:).'
                if isgraphics(ax)
                    cla(ax);
                end
            end
        end

        function appendProgress(app,message)
            value = cellstr(string(app.ProgressTextArea.Value));
            value{end+1,1} = char(string(message));
            value = value(max(1,end-99):end);
            app.ProgressTextArea.Value = value;
        end

        function showError(app,ME)
            app.appendProgress("Error: " + string(ME.message));
        end
    end
end

function cars = defaultCars()
cars = {struct("name","Default lap car","source","RampSpeedApp"), ...
    struct("name","Default acceleration car","source","RampSpeedApp")};
end

function designTable = defaultDesignTable()
designTable = table("default","Default setup","RampSpeedApp", ...
    'VariableNames',{'id','label','source'});
end

function makeLabel(parent,textValue,row,column)
label = uilabel(parent,"Text",textValue);
label.Layout.Row = row;
label.Layout.Column = column;
end

function value = scalarNumber(raw)
value = double(raw);
if ~isscalar(value)
    value = NaN;
end
end

function values = parseSpeedVector(raw)
textValue = strtrim(string(raw));
if isscalar(textValue) && strlength(textValue) == 0
    values = [];
    return
end
if ~isscalar(textValue)
    values = NaN;
    return
end
clean = regexprep(char(textValue),'[,;\[\]]',' ');
tokens = split(strtrim(string(clean)));
tokens = tokens(strlength(tokens) > 0);
values = str2double(tokens).';
if isempty(values) || any(isnan(values))
    values = NaN;
end
end